classdef FixTheInternet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        StartgameButton  matlab.ui.control.Button
        HTML             matlab.ui.control.HTML
        HTML2            matlab.ui.control.HTML
        TextArea         matlab.ui.control.TextArea
        HTML3            matlab.ui.control.HTML
        HTML4            matlab.ui.control.HTML
        HTML5            matlab.ui.control.HTML
    end

    
    properties (Access = private)
        UIState = 0 % Description
        Arduino  % Description
        ResetTimer
    end
   
    properties (Constant)
        StateStart = 0;
        StateInstr1 = 1;
        StateInstr2 = 2;
        StateWaiting = 3;
        StateCongrats = 4;
    end
  
    methods (Access = private)
        function talking (app, source, ~)
            x = readline(source);
            %app.TextArea.Value = x;
            if startsWith(x, "MATLAB BOSTON ON") && app.UIState == app.StateWaiting
                app.setState(app.StateCongrats);
            elseif startsWith(x, "MATLAB Reset") && app.UIState == app.StateCongrats
                app.setState(app.StateStart);
                delete(app.ResetTimer);
            elseif startsWith(x, "CITY") && app.UIState == app.StateWaiting
                app.TextArea.Value = extractAfter(x,"CITY");
            end
        end
        
        function waiting(app, ~, ~)
            delete(app.ResetTimer);
            app.setState(app.StateStart);
        end
        
        function setState(app, newState)
            if newState == app.StateInstr1
                app.Image.Visible = 'off';
                app.HTML3.Visible = 'off';
                app.HTML.Visible = 'on';
                app.HTML2.Visible = 'off';
                app.StartgameButton.Text = 'Continue';
            %instructions
            elseif newState == app.StateInstr2
                app.HTML.Visible = 'off';
                app.HTML5.Visible = 'on';
                app.StartgameButton.Text = 'Continue';
            %Waiting for completion
            elseif newState == app.StateWaiting
                app.HTML5.Visible = 'off';
                app.HTML.Visible = 'off';
                app.HTML2.Visible = 'on';
                app.TextArea.Visible = 'on';
                app.StartgameButton.Visible = 'off';
          %Congrats
            elseif newState == app.StateCongrats
                app.ResetTimer = timer;
                app.ResetTimer.StartDelay = 60;
                app.ResetTimer.TimerFcn = @app.waiting;
                start(app.ResetTimer);
                app.HTML4.Visible = 'on';
                app.HTML2.Visible = 'off';
                app.TextArea.Visible = 'off';
                app.StartgameButton.Visible = 'off';
            %Home again
            elseif newState == app.StateStart
                app.HTML4.Visible = 'off';
                app.HTML2.Visible = 'off';
                app.Image.Visible = 'on';
                app.HTML3.Visible = 'off';
                app.TextArea.Visible = 'off';
                app.StartgameButton.Visible = 'on';
                app.StartgameButton.Text = 'Start game!';
            end
            app.UIState = newState;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Arduino = serialport ("/dev/cu.usbserial-DN06A0EW",9600);
            configureCallback(app.Arduino, "terminator",@app.talking);
        end

        % Button pushed function: StartgameButton
        function StartgameButtonPushed(app, event)
            if app.UIState == app.StateCongrats
                newState = app.StateStart;
            else
                newState = app.UIState + 1;
            end
            app.setState(newState)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tiktok.png';
            app.StartgameButton = uibutton(app.UIFigure, 'push');
            app.StartgameButton.ButtonPushedFcn = createCallbackFcn(app, @StartgameButtonPushed, true);
            app.StartgameButton.Text = 'Start game!';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<style> "font-family: fantasy;"></style><p style="font-size:110%;">    It’s another ordinary afternoon. You just came home from school and would like to watch a video on TikTok. Unfortunately, you cannot connect to the app. You then text your friend. They tell you that they are also having trouble loading TikTok. You ask some more people, and they have the same answer. No one can connect. It looks like TikTok is not working for anyone in the entire city.</p> <p style="font-size:110%;">        	Luckily, you have found our <b>Network Recovery Board</b>. It lets you fix the internet connection between two cities. Each city is a <b>Node. Nodes</b> are locations where information is shared in a computer network. When you make a connection between two cities, you let information move around.        </p>';
            app.HTML.Visible = 'off';
            app.HTML2 = uihtml(app.UIFigure);
            app.HTML2.HTMLSource = '<p>There is a board in front of you with a map of the United States of America. There are 10 cities marked on the map: Los Angeles, Spokane, Las Vegas, Denver, Dallas, Kansas City, Charlotte, Washington D.C., and Boston. The cities have spots for you to make a pathway between them using our internet wires. These paths can be moved around by moving the wires.        	One city on the map has a Red light. This means it has a Network Outage. It cannot share information in the network. This city’s name is also displayed at the bottom of this screen. To make a network that works, you need to move the wires to go around the city with the red light. A green light shows a working connection between two cities. Keep connecting cities with green lights until you have a route that goes from Los Angeles to Boston. Thank you for helping us fix the internet!         	</p>';
            app.HTML2.Visible = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Visible = 'off';
            app.HTML3 = uihtml(app.UIFigure);
            app.HTML3.HTMLSource = 'Videotiktok.html';
            app.HTML3.Visible = 'off';
            app.HTML4 = uihtml(app.UIFigure);
            app.HTML4.HTMLSource = '<p>Congratulations! You have fixed the internet. Networks that can move data along different paths are called <b>Resilient Networks.</b> This means that they can still work when one node is not working. You have just built a resilient network. This has restored your internet access, and you can now watch TikTok videos. Finally!</p>';
            app.HTML4.Visible = 'off';
            app.HTML5 = uihtml(app.UIFigure);
            app.HTML5.HTMLSource = '	<p>If a node has failed, the network will stop working and websites will not work. This board will let you build a network that will restore Boston’s internet connection. In order to get TikTok to work, you need to make a pathway between Los Angeles and Boston. </p><p style="font-size:300%;">Can you fix the internet?”</p>';
            app.HTML5.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FixTheInternet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end